classdef FrequencyResponse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        EnableCheckBox_2         matlab.ui.control.CheckBox
        EnableCheckBox           matlab.ui.control.CheckBox
        Comp2EditField           matlab.ui.control.EditField
        Comp2EditFieldLabel      matlab.ui.control.Label
        Comp1EditField           matlab.ui.control.EditField
        Comp1EditFieldLabel      matlab.ui.control.Label
        ReferenceEditField       matlab.ui.control.EditField
        ReferenceEditFieldLabel  matlab.ui.control.Label
        LoadButton_3             matlab.ui.control.Button
        LoadButton_2             matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file_ref_filename, file_ref_path,  ~] = uigetfile('*.s1p');
            file_ref_path = string(file_ref_path);
            file_ref_filename = string(file_ref_filename);
            app.ReferenceEditField.Value = append(file_ref_path,file_ref_filename);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {567, 567};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {401, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LoadButton_2 = uibutton(app.LeftPanel, 'push');
            app.LoadButton_2.Text = 'Load';
            app.LoadButton_3 = uibutton(app.LeftPanel, 'push');
            app.LoadButton_3.Text = 'Load';
            app.ReferenceEditFieldLabel = uilabel(app.LeftPanel);
            app.ReferenceEditFieldLabel.Text = 'Reference';
            app.ReferenceEditField = uieditfield(app.LeftPanel, 'text');
            app.Comp1EditFieldLabel = uilabel(app.LeftPanel);
            app.Comp1EditFieldLabel.Text = 'Comp #1';
            app.Comp1EditField = uieditfield(app.LeftPanel, 'text');
            app.Comp2EditFieldLabel = uilabel(app.LeftPanel);
            app.Comp2EditFieldLabel.Text = 'Comp #2';
            app.Comp2EditField = uieditfield(app.LeftPanel, 'text');
            app.EnableCheckBox = uicheckbox(app.LeftPanel);
            app.EnableCheckBox.Text = 'Enable';
            app.EnableCheckBox_2 = uicheckbox(app.LeftPanel);
            app.EnableCheckBox_2.Text = 'Enable';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FrequencyResponse

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end